classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ButtonGroup           matlab.ui.container.ButtonGroup
        Button_sin            matlab.ui.control.RadioButton
        Button_rect           matlab.ui.control.RadioButton
        Button_mixedsin       matlab.ui.control.RadioButton
        Button_WGN            matlab.ui.control.RadioButton
        Button_WUN            matlab.ui.control.RadioButton
        Button_SSVEP          matlab.ui.control.RadioButton
        fHzEditFieldLabel     matlab.ui.control.Label
        fHzEditField          matlab.ui.control.NumericEditField
        LsEditFieldLabel      matlab.ui.control.Label
        LsEditField           matlab.ui.control.NumericEditField
        fsHzEditFieldLabel    matlab.ui.control.Label
        fsHzEditField         matlab.ui.control.NumericEditField
        phiEditFieldLabel     matlab.ui.control.Label
        phiEditField          matlab.ui.control.NumericEditField
        aEditFieldLabel       matlab.ui.control.Label
        aEditField            matlab.ui.control.NumericEditField
        AcmEditFieldLabel     matlab.ui.control.Label
        AcmEditField          matlab.ui.control.NumericEditField
        A1cmEditFieldLabel    matlab.ui.control.Label
        A1cmEditField         matlab.ui.control.NumericEditField
        A2cmEditFieldLabel    matlab.ui.control.Label
        A2cmEditField         matlab.ui.control.NumericEditField
        f2HzEditFieldLabel    matlab.ui.control.Label
        f2HzEditField         matlab.ui.control.NumericEditField
        f1HzEditField_2Label  matlab.ui.control.Label
        f1HzEditField         matlab.ui.control.NumericEditField
        phi1EditFieldLabel    matlab.ui.control.Label
        phi1EditField         matlab.ui.control.NumericEditField
        phi2EditFieldLabel    matlab.ui.control.Label
        phi2EditField         matlab.ui.control.NumericEditField
        FFTCheckBox           matlab.ui.control.CheckBox
        PSDCheckBox           matlab.ui.control.CheckBox
        Label                 matlab.ui.control.Label
        meanEditFieldLabel    matlab.ui.control.Label
        meanEditField         matlab.ui.control.NumericEditField
        sigma2EditFieldLabel  matlab.ui.control.Label
        sigma2EditField       matlab.ui.control.NumericEditField
        Buttonwave            matlab.ui.control.Button
        Buttonspectrum        matlab.ui.control.Button
        Label_2               matlab.ui.control.Label
        Label_3               matlab.ui.control.Label
        Label_4               matlab.ui.control.Label
        DropDown              matlab.ui.control.DropDown
        fcHzLabel             matlab.ui.control.Label
        fcHzEditField         matlab.ui.control.NumericEditField
        Label_5               matlab.ui.control.Label
        DropDown_2            matlab.ui.control.DropDown
        Label_6               matlab.ui.control.Label
        DropDown_3            matlab.ui.control.DropDown
        Button_3              matlab.ui.control.Button
        dHzLabel              matlab.ui.control.Label
        dHzEditField          matlab.ui.control.NumericEditField
        TabGroup              matlab.ui.container.TabGroup
        Tab                   matlab.ui.container.Tab
        UIAxes1               matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes3               matlab.ui.control.UIAxes
        UIAxes4               matlab.ui.control.UIAxes
        Tab_2                 matlab.ui.container.Tab
        UITable               matlab.ui.control.Table
        Label_7               matlab.ui.control.Label
        DropDown_4            matlab.ui.control.DropDown
        ssvepButton           matlab.ui.control.Button
        Button_4              matlab.ui.control.Button
        Label_8               matlab.ui.control.Label
        DropDown_5            matlab.ui.control.DropDown
        Label_9               matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            % "请选择要产生的波形"按钮组的回调函数
            % 隐藏上次按下的按键对应的按键或窗口
            switch event.OldValue.Text
                case '正弦波'
                    app.fHzEditField.Visible = 'off'; app.fHzEditFieldLabel.Visible = 'off';
                    app.AcmEditField.Visible = 'off'; app.AcmEditFieldLabel.Visible = 'off';
                    app.phiEditField.Visible = 'off'; app.phiEditFieldLabel.Visible = 'off';
                case '方波'
                    app.fHzEditField.Visible = 'off'; app.fHzEditFieldLabel.Visible = 'off';
                    app.AcmEditField.Visible = 'off'; app.AcmEditFieldLabel.Visible = 'off';
                    app.aEditField.Visible = 'off'; app.aEditFieldLabel.Visible = 'off';
                case '混合正弦波'
                    app.f1HzEditField.Visible = 'off'; app.f1HzEditField_2Label.Visible = 'off';
                    app.f2HzEditField.Visible = 'off'; app.f2HzEditFieldLabel.Visible = 'off';
                    app.A1cmEditField.Visible = 'off'; app.A1cmEditFieldLabel.Visible = 'off';
                    app.A2cmEditField.Visible = 'off'; app.A2cmEditFieldLabel.Visible = 'off';
                    app.phi1EditField.Visible = 'off'; app.phi1EditFieldLabel.Visible = 'off';
                    app.phi2EditField.Visible = 'off'; app.phi2EditFieldLabel.Visible = 'off';
                case '高斯白噪声'
                    app.meanEditField.Visible = 'off'; app.sigma2EditField.Visible = 'off';
                    app.meanEditFieldLabel.Visible = 'off'; app.sigma2EditFieldLabel.Visible = 'off';
                    
                case  '均匀白噪声'
                    app.meanEditField.Visible = 'off'; app.sigma2EditField.Visible = 'off';
                    app.meanEditFieldLabel.Visible = 'off'; app.sigma2EditFieldLabel.Visible = 'off';
                    
            end
            % 显示当前按下的按键对应的按键或窗口
            switch event.NewValue.Text
                case '正弦波'
                    app.fHzEditField.Visible = 'on'; app.fHzEditFieldLabel.Visible = 'on';
                    app.AcmEditField.Visible = 'on'; app.AcmEditFieldLabel.Visible = 'on';
                    app.phiEditField.Visible = 'on'; app.phiEditFieldLabel.Visible = 'on';
                    
                case '方波'     
                    app.fHzEditField.Visible = 'on'; app.fHzEditFieldLabel.Visible = 'on';
                    app.AcmEditField.Visible = 'on'; app.AcmEditFieldLabel.Visible = 'on';
                    app.aEditField.Visible = 'on'; app.aEditFieldLabel.Visible = 'on';
                case '混合正弦波'
                    app.f1HzEditField.Visible = 'on'; app.f1HzEditField_2Label.Visible = 'on';
                    app.f2HzEditField.Visible = 'on'; app.f2HzEditFieldLabel.Visible = 'on';
                    app.A1cmEditField.Visible = 'on'; app.A1cmEditFieldLabel.Visible = 'on';
                    app.A2cmEditField.Visible = 'on'; app.A2cmEditFieldLabel.Visible = 'on';
                    app.phi1EditField.Visible = 'on'; app.phi1EditFieldLabel.Visible = 'on';
                    app.phi2EditField.Visible = 'on'; app.phi2EditFieldLabel.Visible = 'on';
                case '高斯白噪声'
                    app.meanEditField.Visible = 'on'; app.sigma2EditField.Visible = 'on';
                    app.meanEditFieldLabel.Visible = 'on'; app.sigma2EditFieldLabel.Visible = 'on';
                case  '均匀白噪声'
                   app.meanEditField.Visible = 'on'; app.sigma2EditField.Visible = 'on';
                   app.meanEditFieldLabel.Visible = 'on'; app.sigma2EditFieldLabel.Visible = 'on';
            end
        end

        % Button pushed function: Buttonwave
        function ButtonwavePushed(app, event)
            % "产生时域波形"按钮的回调函数
            selectedButton = app.ButtonGroup.SelectedObject;  % 当前选择的信号类型
            fs = 1000;  % 采样率
            L = app.LsEditField.Value;  % 信号时间长度
            t = 0:1/fs:L-1/fs;          % 时间向量
            switch selectedButton.Text  % 产生时域波形的表达式
                case '正弦波'
                    f = app.fHzEditField.Value;
                    phi = app.phiEditField.Value*pi/180;
                    A = app.AcmEditField.Value;
                    y = A*sin(2*pi*f*t + phi);
                case '方波'
                    A = app.AcmEditField.Value;
                    f = app.fHzEditField.Value;
                    duty = app.aEditField.Value;
                    y = A*square(t*2*pi*f,duty);
                case '混合正弦波'
                    f1 = app.f1HzEditField.Value; A1 = app.A1cmEditField.Value; phi1 = app.phi1EditField.Value*pi/180;
                    f2 = app.f2HzEditField.Value; A2 = app.A2cmEditField.Value; phi2 = app.A2cmEditField.Value*pi/180;
                    y = A1*sin(2*pi*f1*t+phi1)+A2*sin(2*pi*f2*t+phi2);
                case '高斯白噪声'
                    sigma2 = app.sigma2EditField.Value;
                    mean = app.meanEditField.Value;
                    x = randn(1,L*fs);                  % 产生标准正态分布序列
                    y = x*sqrt(sigma2) + mean;          % 设置序列的均值和方差
                case '均匀白噪声'
                    sigma2 = app.sigma2EditField.Value;
                    mean = app.meanEditField.Value;
                    a = mean + sqrt(3)*sqrt(sigma2);
                    b = mean - sqrt(3)*sqrt(sigma2);
                    y = unifrnd(b,a,size(t));          % 产生区间[b,a]上均匀分布序列
                case '加载SSVEP信号'
                    N=6000;                            %采样点数
                    load('SSVEP_1_1kHz.mat');
                    x_sum_1=zeros(1,N);
                    for i=1:1:100
                        x_sum_1=x_sum_1+x(1,1:N,i);                       
                    end
                    x_average_1=x_sum_1/100;
                    y = x_average_1;
                    t=(0:1:N-1)/fs;
            end
            cla(app.UIAxes1); cla(app.UIAxes2); cla(app.UIAxes3); cla(app.UIAxes4);
            plot(app.UIAxes1,t,y);    % 绘制时域波形
            axis(app.UIAxes1,'tight');
            xlabel(app.UIAxes1,'时间t/s'); ylabel(app.UIAxes1,'幅度/cm'); title(app.UIAxes1,'时域波形');
        end

        % Button pushed function: Buttonspectrum
        function ButtonspectrumPushed(app, event)
            % "进行频谱分析"按键的回调函数
            valueFFT = app.FFTCheckBox.Value;  % 当前选择的频谱分析类型           
            flag1 = 0; flag2 = 0; % 标记，显示ssvep信号频谱时需调整横坐标范围         
            if valueFFT           % 进行FFT分析
                fs = 1000; L = app.LsEditField.Value;
                t = 0:1/fs:L-1/fs;               
                selectedButton = app.ButtonGroup.SelectedObject;
                switch selectedButton.Text
                    case '正弦波'
                        f = app.fHzEditField.Value;
                        phi = app.phiEditField.Value*pi/180;
                        A = app.AcmEditField.Value;
                        y = A*sin(2*pi*f*t + phi);                        
                    case '方波'
                        f = app.fHzEditField.Value;
                        a = app.aEditField.Value;
                        A = app.AcmEditField.Value;
                        y = A*square(t*2*pi*f,a);                        
                    case '混合正弦波'
                        f1 = app.f1HzEditField.Value; A1 = app.A1cmEditField.Value; phi1 = app.phi1EditField.Value*pi/180;
                        f2 = app.f2HzEditField.Value; A2 = app.A2cmEditField.Value; phi2 = app.A2cmEditField.Value*pi/180;
                        t = 0:1/fs:L-1/fs;
                        y = A1*sin(2*pi*f1*t+phi1)+A2*sin(2*pi*f2*t+phi2);                        
                    case '高斯白噪声'
                        sigma2 = app.sigma2EditField.Value;
                        mean = app.meanEditField.Value;
                        x = randn(1,L*fs);
                        y = x*sqrt(sigma2) + mean;                      
                    case '均匀白噪声'
                        sigma2 = app.sigma2EditField.Value;
                        mean = app.meanEditField.Value;
                        a = mean + sqrt(3)*sqrt(sigma2);
                        b = mean - sqrt(3)*sqrt(sigma2);
                        y = unifrnd(b,a,size(t));
                    case '加载SSVEP信号'
                        flag1 = 1;
                        N=6000; %采样点数
                        load('SSVEP_1_1kHz.mat');
                        x_sum_1=zeros(1,N);
                        for i=1:1:100
                            x_sum_1=x_sum_1+x(1,1:N,i);                            
                        end
                        x_average_1=x_sum_1/100;
                        y = x_average_1;
                        t=(0:1:N-1)/fs;
                end
                % 求信号y的FFT
                Y = fft(y);
                ly = length(y);
                P2 = abs(Y/ly);
                P1 = P2(1:ly/2+1);
                P1(2:end-1) = 2*P1(2:end-1);
                Y1 = fftshift(Y); % 频谱中心化
                tol = 1e-6;
                Y1(abs(Y1) < tol) = 0; % 将频谱幅值太小的部分去除
                theta = angle(Y1);
                fc = (-ly/2:ly/2-1)/ly*fs; fn = fs*(0:(ly/2))/ly;
                % 绘制幅度和相位谱
                plot(app.UIAxes3,fn,P1);
                xlabel(app.UIAxes3,'频率f/Hz'); ylabel(app.UIAxes3,'幅度谱'); title(app.UIAxes3,'FFT幅度谱');
                plot(app.UIAxes4,fc,theta);
                xlabel(app.UIAxes4,'频率f/Hz'); ylabel(app.UIAxes4,'相位'); title(app.UIAxes4,'FFT相位谱');
                if flag1==0    % 调整SSVEP信号的频谱坐标范围，便于显示
                    axis(app.UIAxes3,'tight');
                    axis(app.UIAxes4,'tight');
                else
                    xlim(app.UIAxes3,[0 100]);
                    xlim(app.UIAxes4,[-100 100]);
                end
            else
                cla(app.UIAxes3); cla(app.UIAxes4);
            end            
            valuePSD = app.PSDCheckBox.Value;
            if valuePSD    % 进行功率谱分析
                fs = 1000; L = app.LsEditField.Value;
                t = 0:1/fs:L-1/fs;
                selectedButton = app.ButtonGroup.SelectedObject;
                switch selectedButton.Text
                    case '正弦波'
                        f = app.fHzEditField.Value;
                        phi = app.phiEditField.Value*pi/180;
                        A = app.AcmEditField.Value;
                        y = A*sin(2*pi*f*t + phi);
                    case '方波'
                        f = app.fHzEditField.Value;
                        a = app.aEditField.Value;
                        A = app.AcmEditField.Value;
                        y = A*square(t*2*pi*f,a);
                    case '混合正弦波'
                        f1 = app.f1HzEditField.Value; A1 = app.A1cmEditField.Value; phi1 = app.phi1EditField.Value*pi/180;
                        f2 = app.f2HzEditField.Value; A2 = app.A2cmEditField.Value; phi2 = app.A2cmEditField.Value*pi/180;
                        t = 0:1/fs:L-1/fs;
                        y = A1*sin(2*pi*f1*t+phi1)+A2*sin(2*pi*f2*t+phi2);
                    case '高斯白噪声'
                        sigma2 = app.sigma2EditField.Value;
                        mean = app.meanEditField.Value;
                        x = randn(1,L*fs);
                        y = x*sqrt(sigma2) + mean;
                    case '均匀白噪声'
                        sigma2 = app.sigma2EditField.Value;
                        mean = app.meanEditField.Value;
                        a = mean + sqrt(3)*sqrt(sigma2);
                        b = mean - sqrt(3)*sqrt(sigma2);
                        y = unifrnd(b,a,size(t));
                    case '加载SSVEP信号'
                        flag2 = 1;
                        N=6000; %采样点数
                        load('SSVEP_1_1kHz.mat');
                        x_sum_1=zeros(1,N);
                        for i=1:1:100
                            x_sum_1=x_sum_1+x(1,1:N,i);
                            
                        end
                        x_average_1=x_sum_1/100;
                        y = x_average_1;
                end
                % 周期图法估计功率谱
                [Pxx,f] = periodogram(y,hamming(length(y)),[],fs);
                plot(app.UIAxes2,f,Pxx);
                if flag2==0
                    axis(app.UIAxes2,'tight');
                else
                    xlim(app.UIAxes2,[0 100]);
                end
                xlabel(app.UIAxes2,'频率f/Hz'); ylabel(app.UIAxes2,'功率谱密度'); title(app.UIAxes2,'功率谱');
            else
                cla(app.UIAxes2);
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            % "实验类型"下拉框的回调函数，控制不同实验类型所需的UI构件的显示和隐藏过程
            value = app.DropDown.Value;
            app.LsEditField.Visible = 'on'; app.LsEditFieldLabel.Visible = 'on';
            app.fsHzEditField.Visible = 'on'; app.fsHzEditFieldLabel.Visible = 'on';
            switch event.PreviousValue
                case '信号产生及频谱分析'
                    app.ButtonGroup.Visible = 'off'; app.Label.Visible = 'off';
                    app.FFTCheckBox.Visible = 'off'; app.PSDCheckBox.Visible = 'off';
                    app.Buttonwave.Visible = 'off'; app.Buttonspectrum.Visible = 'off';
                    switch app.ButtonGroup.SelectedObject.Text
                        case '正弦波'
                            app.fHzEditField.Visible = 'off'; app.fHzEditFieldLabel.Visible = 'off';
                            app.AcmEditField.Visible = 'off'; app.AcmEditFieldLabel.Visible = 'off';
                            app.phiEditField.Visible = 'off'; app.phiEditFieldLabel.Visible = 'off';
                        case '方波'
                            app.fHzEditField.Visible = 'off'; app.fHzEditFieldLabel.Visible = 'off';
                            app.AcmEditField.Visible = 'off'; app.AcmEditFieldLabel.Visible = 'off';
                            app.aEditField.Visible = 'off'; app.aEditFieldLabel.Visible = 'off';
                        case '高斯白噪声'
                            app.meanEditField.Visible = 'off'; app.sigma2EditField.Visible = 'off';
                            app.meanEditFieldLabel.Visible = 'off'; app.sigma2EditFieldLabel.Visible = 'off';
                        case  '均匀白噪声'
                            
                            app.meanEditField.Visible = 'off'; app.sigma2EditField.Visible = 'off';
                            app.meanEditFieldLabel.Visible = 'off'; app.sigma2EditFieldLabel.Visible = 'off';
                    end
                case '数字低通滤波器设计与滤波'
                    app.DropDown_3.Visible = 'off'; app.Label_6.Visible = 'off';
                    app.DropDown_2.Visible = 'off'; app.Label_5.Visible = 'off';
                    app.Label_9.Visible = 'off'; app.dHzEditField.Visible = 'off'; app.dHzLabel.Visible = 'off';
                    app.Button_3.Visible = 'off';
                    app.fcHzEditField.Visible = 'off'; app.fcHzLabel.Visible = 'off';
                    app.f1HzEditField.Visible = 'off'; app.f1HzEditField_2Label.Visible = 'off';
                    app.f2HzEditField.Visible = 'off'; app.f2HzEditFieldLabel.Visible = 'off';
                    app.A1cmEditField.Visible = 'off'; app.A1cmEditFieldLabel.Visible = 'off';
                    app.A2cmEditField.Visible = 'off'; app.A2cmEditFieldLabel.Visible = 'off';
                    app.phi1EditField.Visible = 'off'; app.phi1EditFieldLabel.Visible = 'off';
                    app.phi2EditField.Visible = 'off'; app.phi2EditFieldLabel.Visible = 'off';
                case '数字高通滤波器设计与滤波'
                    app.DropDown_3.Visible = 'off'; app.Label_6.Visible = 'off';
                    app.DropDown_2.Visible = 'off'; app.Label_5.Visible = 'off';
                    app.Label_9.Visible = 'off'; app.dHzEditField.Visible = 'off'; app.dHzLabel.Visible = 'off';
                    app.Button_3.Visible = 'off';
                    app.fcHzEditField.Visible = 'off'; app.fcHzLabel.Visible = 'off';
                    app.f1HzEditField.Visible = 'off'; app.f1HzEditField_2Label.Visible = 'off';
                    app.f2HzEditField.Visible = 'off'; app.f2HzEditFieldLabel.Visible = 'off';
                    app.A1cmEditField.Visible = 'off'; app.A1cmEditFieldLabel.Visible = 'off';
                    app.A2cmEditField.Visible = 'off'; app.A2cmEditFieldLabel.Visible = 'off';
                    app.phi1EditField.Visible = 'off'; app.phi1EditFieldLabel.Visible = 'off';
                    app.phi2EditField.Visible = 'off'; app.phi2EditFieldLabel.Visible = 'off';
                case 'ssvep信号识别'
                    app.DropDown_4.Visible = 'off'; app.Label_7.Visible = 'off';
                    app.DropDown_5.Visible = 'off'; app.Label_8.Visible = 'off';
                    app.ssvepButton.Visible = 'off'; app.Button_4.Visible = 'off';
            end
            switch value
                case '信号产生及频谱分析'
                    app.ButtonGroup.Visible = 'on'; app.Label.Visible = 'on';
                    app.FFTCheckBox.Visible = 'on'; app.PSDCheckBox.Visible = 'on';
                    app.Buttonwave.Visible = 'on'; app.Buttonspectrum.Visible = 'on';
                    switch app.ButtonGroup.SelectedObject.Text
                        case '正弦波'
                            app.fHzEditField.Visible = 'on'; app.fHzEditFieldLabel.Visible = 'on';
                            app.AcmEditField.Visible = 'on'; app.AcmEditFieldLabel.Visible = 'on';
                            app.phiEditField.Visible = 'on'; app.phiEditFieldLabel.Visible = 'on';
                        case '方波'
                            app.fHzEditField.Visible = 'on'; app.fHzEditFieldLabel.Visible = 'on';
                            app.AcmEditField.Visible = 'on'; app.AcmEditFieldLabel.Visible = 'on';
                            app.aEditField.Visible = 'on'; app.aEditFieldLabel.Visible = 'on';
                        case '高斯白噪声'
                            app.meanEditField.Visible = 'on'; app.sigma2EditField.Visible = 'on';
                            app.meanEditFieldLabel.Visible = 'on'; app.sigma2EditFieldLabel.Visible = 'on';
                        case  '均匀白噪声'
                            
                            app.meanEditField.Visible = 'on'; app.sigma2EditField.Visible = 'on';
                            app.meanEditFieldLabel.Visible = 'on'; app.sigma2EditFieldLabel.Visible = 'on';
                    end
                case '数字低通滤波器设计与滤波'
                    app.DropDown_3.Visible = 'on'; app.Label_6.Visible = 'on';
                    app.DropDown_2.Visible = 'on'; app.Label_5.Visible = 'on';
                    app.Label_9.Visible = 'on'; app.dHzEditField.Visible = 'on'; app.dHzLabel.Visible = 'on';
                    app.Button_3.Visible = 'on';
                    app.fcHzEditField.Visible = 'on'; app.fcHzLabel.Visible = 'on';
                    app.f1HzEditField.Visible = 'on'; app.f1HzEditField_2Label.Visible = 'on';
                    app.f2HzEditField.Visible = 'on'; app.f2HzEditFieldLabel.Visible = 'on';
                    app.A1cmEditField.Visible = 'on'; app.A1cmEditFieldLabel.Visible = 'on';
                    app.A2cmEditField.Visible = 'on'; app.A2cmEditFieldLabel.Visible = 'on';
                    app.phi1EditField.Visible = 'on'; app.phi1EditFieldLabel.Visible = 'on';
                    app.phi2EditField.Visible = 'on'; app.phi2EditFieldLabel.Visible = 'on';
                case '数字高通滤波器设计与滤波'
                    app.DropDown_3.Visible = 'on'; app.Label_6.Visible = 'on';
                    app.DropDown_2.Visible = 'on'; app.Label_5.Visible = 'on';
                    app.Label_9.Visible = 'on'; app.dHzEditField.Visible = 'on'; app.dHzLabel.Visible = 'on';
                    app.Button_3.Visible = 'on';
                    app.fcHzEditField.Visible = 'on'; app.fcHzLabel.Visible = 'on';
                    app.f1HzEditField.Visible = 'on'; app.f1HzEditField_2Label.Visible = 'on';
                    app.f2HzEditField.Visible = 'on'; app.f2HzEditFieldLabel.Visible = 'on';
                    app.A1cmEditField.Visible = 'on'; app.A1cmEditFieldLabel.Visible = 'on';
                    app.A2cmEditField.Visible = 'on'; app.A2cmEditFieldLabel.Visible = 'on';
                    app.phi1EditField.Visible = 'on'; app.phi1EditFieldLabel.Visible = 'on';
                    app.phi2EditField.Visible = 'on'; app.phi2EditFieldLabel.Visible = 'on';
                case 'ssvep信号识别'
                    app.DropDown_4.Visible = 'on'; app.Label_7.Visible = 'on';
                    app.DropDown_5.Visible = 'on'; app.Label_8.Visible = 'on';
                    app.ssvepButton.Visible = 'on'; app.Button_4.Visible = 'on';
                    app.LsEditField.Visible = 'off'; app.LsEditFieldLabel.Visible = 'off';
                    app.fsHzEditField.Visible = 'off'; app.fsHzEditFieldLabel.Visible = 'off';
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            % "开始滤波器分析"按键的回调函数
            fs = app.fsHzEditField.Value; Wp = app.fcHzEditField.Value/(fs/2);  % 采样率和通带截止频率
            wid = app.dHzEditField.Value; L = app.LsEditField.Value;            % 过渡带宽度和信号时间长度
            cla(app.UIAxes1); cla(app.UIAxes2); cla(app.UIAxes3); cla(app.UIAxes4);
            switch app.DropDown.Value
                case '数字低通滤波器设计与滤波'
                    Rp = 3; As = 60;        % 通带纹波和阻带衰减
                    Ws = Wp + wid/(fs/2);   % 计算阻带截止频率
                    switch app.DropDown_2.Value % 构造低通滤波器
                        case '巴特沃斯数字滤波器'
                            [n,Wn] = buttord(Wp,Ws,Rp,As);
                            [b,a] = butter(n,Wn);
                        case '切比雪夫1型数字滤波器'
                            [n,Wn] = cheb1ord(Wp,Ws,Rp,As);
                            [b,a] = cheby1(n,Rp,Wn);
                        case '切比雪夫2型数字滤波器'
                            [n,Wn] = cheb2ord(Wp,Ws,Rp,As);
                            [b,a] = cheby1(n,Rp,Wn);
                        case '椭圆数字滤波器'
                            [n,Wn] = ellipord(Wp,Ws,Rp,As);
                            [b,a] = ellip(n,Rp,60,Wn);
                    end
                    switch app.DropDown_3.Value  % 显示滤波器频率特性或对混合正弦波滤波并显示频谱
                        case '显示幅频、相频响应'
                            [h,f] = freqz(b,a,512,fs);
                            plot(app.UIAxes1,f,20*log10(abs(h))); axis(app.UIAxes1,'tight');
                            xlabel(app.UIAxes1,'频率/Hz'); ylabel(app.UIAxes1,'幅频响应/dB'); title(app.UIAxes1,'滤波器幅频响应');
                            theta = angle(h);
                            plot(app.UIAxes2,f,theta/pi*180); axis(app.UIAxes2,'tight');
                            xlabel(app.UIAxes2,'频率/Hz'); ylabel(app.UIAxes2,'相频响应/度'); title(app.UIAxes2,'滤波器相频响应');
                        case '对混合正弦波滤波，显示幅度谱'
                            f1 = app.f1HzEditField.Value; A1 = app.A1cmEditField.Value; phi1 = app.phi1EditField.Value*pi/180;
                            f2 = app.f2HzEditField.Value; A2 = app.A2cmEditField.Value; phi2 = app.A2cmEditField.Value*pi/180; % 混合正弦波的参数
                            t = 0:1/fs:L-1/fs;
                            y = A1*sin(2*pi*f1*t+phi1)+A2*sin(2*pi*f2*t+phi2);
                            y1 = filter(b,a,y);
                            % 分别求滤波前和滤波后信号的FFT
                            N = 2^nextpow2(length(t));
                            Y1 = fft(y1,N); Y = fft(y,N);
                            P2Y1 = abs(Y1/N); P2Y = abs(Y/N);
                            P1Y1 = P2Y1(1:N/2+1); P1Y = P2Y(1:N/2+1);
                            P1Y1(2:end-1) = 2*P1Y1(2:end-1); P1Y(2:end-1) = 2*P1Y(2:end-1);
                            f = fs*(0:(N/2))/N;
                            plot(app.UIAxes1,t,y); axis(app.UIAxes1,'tight');
                            plot(app.UIAxes2,f,P1Y(1:N/2+1)); axis(app.UIAxes2,'tight');
                            plot(app.UIAxes3,t,y1); axis(app.UIAxes3,'tight');
                            plot(app.UIAxes4,f,P1Y1(1:N/2+1)); axis(app.UIAxes4,'tight');
                            xlabel(app.UIAxes1,'时间t/s'); ylabel(app.UIAxes1,'x/cm'); title(app.UIAxes1,'滤波前时域波形');
                            xlabel(app.UIAxes3,'时间t/s'); ylabel(app.UIAxes3,'y/cm'); title(app.UIAxes3,'滤波后时域波形');
                            xlabel(app.UIAxes2,'频率f/Hz'); ylabel(app.UIAxes2,'幅度谱'); title(app.UIAxes2,'滤波前频谱的幅度');
                            xlabel(app.UIAxes4,'频率f/Hz'); ylabel(app.UIAxes4,'幅度谱'); title(app.UIAxes4,'滤波后频谱的幅度');
                        case '对混合正弦波滤波，显示相位谱'
                            f1 = app.f1HzEditField.Value; A1 = app.A1cmEditField.Value; phi1 = app.phi1EditField.Value*pi/180;
                            f2 = app.f2HzEditField.Value; A2 = app.A2cmEditField.Value; phi2 = app.A2cmEditField.Value*pi/180;
                            t = 0:1/fs:L-1/fs;
                            x = A1*sin(2*pi*f1*t+phi1)+A2*sin(2*pi*f2*t+phi2);
                            y = filter(b,a,x);
                            % 求滤波前后信号的频谱
                            N = length(y);
                            Y = fft(y); X = fft(x);
                            Y1 = fftshift(Y); X1 = fftshift(X);
                            tol = 1e-6;
                            Y1(abs(Y1)<tol) = 0; X1(abs(X1)<tol) = 0;
                            thetay = angle(Y1)/pi*180; thetax = angle(X1)/pi*180;
                            f = fs*(-N/2:(N/2-1))/N;
                            plot(app.UIAxes1,t,x); stem(app.UIAxes2,f,thetax); axis(app.UIAxes1,'tight'); axis(app.UIAxes2,'tight');
                            plot(app.UIAxes3,t,y); plot(app.UIAxes4,f,thetay); axis(app.UIAxes3,'tight'); axis(app.UIAxes4,'tight');
                            xlabel(app.UIAxes1,'时间t/s'); ylabel(app.UIAxes1,'x/cm'); title(app.UIAxes1,'滤波前时域波形');
                            xlabel(app.UIAxes3,'时间t/s'); ylabel(app.UIAxes3,'y/cm'); title(app.UIAxes3,'滤波后时域波形');
                            xlabel(app.UIAxes2,'频率f/Hz'); ylabel(app.UIAxes2,'相位/度'); title(app.UIAxes2,'滤波前频谱的相位');
                            xlabel(app.UIAxes4,'频率f/Hz'); ylabel(app.UIAxes4,'相位/度'); title(app.UIAxes4,'滤波后频谱的相位');
                    end
                case '数字高通滤波器设计与滤波'
                    b = fir1(70,Wp,'high');  % 构造线性相位FIR滤波器
                    switch app.DropDown_3.Value
                        case '显示幅频、相频响应'
                            [h,f] = freqz(b,1,512,fs);
                            plot(app.UIAxes1,f,20*log10(abs(h))); axis(app.UIAxes1,'tight');
                            xlabel(app.UIAxes1,'频率f/Hz'); ylabel(app.UIAxes1,'幅频响应/dB'); title(app.UIAxes1,'滤波器幅频相应');
                            theta = angle(h);
                            plot(app.UIAxes2,f,theta/pi*180); axis(app.UIAxes2,'tight');
                            xlabel(app.UIAxes2,'频率/Hz'); ylabel(app.UIAxes2,'相频响应/度'); title(app.UIAxes2,'滤波器相频响应');
                        case '对混合正弦波滤波，显示幅度谱'
                            f1 = app.f1HzEditField.Value; A1 = app.A1cmEditField.Value; phi1 = app.phi1EditField.Value*pi/180;
                            f2 = app.f2HzEditField.Value; A2 = app.A2cmEditField.Value; phi2 = app.A2cmEditField.Value*pi/180;
                            t = 0:1/fs:L-1/fs;
                            y = A1*sin(2*pi*f1*t+phi1)+A2*sin(2*pi*f2*t+phi2);
                            y1 = filter(b,1,y);
                            N = 2^nextpow2(length(t));
                            Y1 = fft(y1,N); Y = fft(y,N);
                            P2Y1 = abs(Y1/N); P2Y = abs(Y/N);
                            P1Y1 = P2Y1(1:N/2+1); P1Y = P2Y(1:N/2+1);
                            P1Y1(2:end-1) = 2*P1Y1(2:end-1); P1Y(2:end-1) = 2*P1Y(2:end-1);
                            f = fs*(0:(N/2))/N;              
                            plot(app.UIAxes1,t,y); axis(app.UIAxes1,'tight');
                            plot(app.UIAxes2,f,P1Y(1:N/2+1)); axis(app.UIAxes2,'tight');
                            plot(app.UIAxes3,t,y1); axis(app.UIAxes3,'tight');
                            plot(app.UIAxes4,f,P1Y1(1:N/2+1)); axis(app.UIAxes4,'tight');
                            xlabel(app.UIAxes1,'时间t/s'); ylabel(app.UIAxes1,'x/cm'); title(app.UIAxes1,'滤波前时域波形');
                            xlabel(app.UIAxes3,'时间t/s'); ylabel(app.UIAxes3,'y/cm'); title(app.UIAxes3,'滤波后时域波形');
                            xlabel(app.UIAxes2,'频率f/Hz'); ylabel(app.UIAxes2,'幅度谱'); title(app.UIAxes2,'滤波前频谱的幅度');
                            xlabel(app.UIAxes4,'频率f/Hz'); ylabel(app.UIAxes4,'幅度谱'); title(app.UIAxes4,'滤波后频谱的幅度');
                        case '对混合正弦波滤波，显示相位谱'
                            f1 = app.f1HzEditField.Value; A1 = app.A1cmEditField.Value; phi1 = app.phi1EditField.Value*pi/180;
                            f2 = app.f2HzEditField.Value; A2 = app.A2cmEditField.Value; phi2 = app.A2cmEditField.Value*pi/180;
                            t = 0:1/fs:L-1/fs;
                            x = A1*sin(2*pi*f1*t+phi1)+A2*sin(2*pi*f2*t+phi2);
                            y = filter(b,1,x);
                            N = length(y);
                            Y = fft(y); X = fft(x);
                            Y1 = fftshift(Y); X1 = fftshift(X);
                            tol = 1e-6;
                            Y1(abs(Y1)<tol) = 0; X1(abs(X1)<tol) = 0;
                            thetay = angle(Y1)/pi*180; thetax = angle(X1)/pi*180;
                            f = fs*(-N/2:(N/2-1))/N;
                            plot(app.UIAxes1,t,x); stem(app.UIAxes2,f,thetax); axis(app.UIAxes1,'tight'); axis(app.UIAxes2,'tight');
                            plot(app.UIAxes3,t,y); plot(app.UIAxes4,f,thetay); axis(app.UIAxes3,'tight'); axis(app.UIAxes4,'tight');
                            xlabel(app.UIAxes1,'时间t/s'); ylabel(app.UIAxes1,'x/cm'); title(app.UIAxes1,'滤波前时域波形');
                            xlabel(app.UIAxes3,'时间t/s'); ylabel(app.UIAxes3,'y/cm'); title(app.UIAxes3,'滤波后时域波形');
                            xlabel(app.UIAxes2,'频率f/Hz'); ylabel(app.UIAxes2,'相位/度'); title(app.UIAxes2,'滤波前频谱的相位');
                            xlabel(app.UIAxes4,'频率f/Hz'); ylabel(app.UIAxes4,'相位/度'); title(app.UIAxes4,'滤波后频谱的相位');
                    end
            end
        end

        % Button pushed function: ssvepButton
        function ssvepButtonPushed(app, event)
            % "查看SSVEP信号时域频域图像"按键的回调函数
            cla(app.UIAxes1); cla(app.UIAxes2); cla(app.UIAxes3); cla(app.UIAxes4);
            if length(app.DropDown_4.Value) == 3    % 根据字符串长度，判断试次是否是个位数，并从中提取试次数字
                num = str2num(app.DropDown_4.Value(3));
            elseif length(app.DropDown_4.Value) == 4
                num = str2num(app.DropDown_4.Value(3:4));
            end
            load('eeg.mat');    % 加载SSVEP信号数据
            fs = 1000; L = 3.5; % 采样率和信号时长
            t = 0:1/fs:L-1/fs;
            x1 = x(6,:,num);    % 选取结果较明显的6号导联来绘图
            y = bandpass(x1,[8 40],fs);   % 带通滤波
            plot(app.UIAxes1,t,y); axis(app.UIAxes1,'tight');
            xlabel(app.UIAxes1,'时间t/s'); title(app.UIAxes1,'SSVEP信号波形'); ylabel(app.UIAxes1,'');
            % 进行功率谱或FFT分析
            if app.DropDown_5.Value == '功率谱'
                [p,f] = periodogram(y,hamming(length(x1)),[],fs);
                [M,I] = max(p);
                plot(app.UIAxes2,f,p,f(I),M);
            elseif app.DropDown_5.Value == 'FFT'
                Y = fft(y); N = length(y);
                P2 = abs(Y/N);
                P1 = P2(1:N/2+1);
                P1(2:end-1) = 2*P1(2:end-1);
                [M,I] = max(P1);
                f = fs*(0:N/2)/N;
                plot(app.UIAxes2,f,P1,f(I),M);
            end            
            axis(app.UIAxes2,'tight');
            text(app.UIAxes2,f(I),M,['X: ',num2str(f(I)),newline,'Y: ',num2str(M)]);
            xlim(app.UIAxes2,[0 50]);
            xlabel(app.UIAxes2,'频率f/Hz'); title(app.UIAxes2,'SSVEP信号频谱'); ylabel(app.UIAxes2,'');
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            % "对20个试次进行目标识别（支持功率谱分析）"的回调函数
            % 用功率谱来识别脑电信号对应的模式频率
            load('eeg.mat');
            fs = app.fsHzEditField.Value; L = 3.5;
            t = 0:1/fs:L-1/fs;
            f_table = [8,8.5,9,9.5,10,10.5,11,11.5,12,12.5];   % 存放刺激频率标称值
            result = zeros(1,20); result2 = zeros(20,8);
            count_total = 0;
            for i = 1:20   % 对20个试次进行识别
                count=0;
                for j = 1:8  % 对8个导联进行识别
                    x1 = x(j,:,i);
                    y = bandpass(x1,[5 40],fs);
                    [p,f] = periodogram(y,hamming(length(x1)),[],fs);
                    [M,I] = max(p);  % 找到功率谱密度的最大值点
                    if i>=1 && i<=10
                        [Ml,Il] = max(p(f>=2*f_table(i)-5 & f<=2*f_table(i)+5));
                        if f(I)-f_table(i)<=0.1  % 若功率谱最大值点的频率和标称频率相差小于0.1Hz，认为识别成功
                            count = count+1;     % 记录识别成功的导联个数
                            result2(i,j)=result2(i,j)+1;
                        elseif f(Il)-2*f_table(i)<=0.1  % 若基频识别不成功，采用二倍频识别，提高准确率
                            count = count+1;
                            result2(i,j)=result2(i,j)+1;
                        end
                    elseif i>=11 && i<=20      % 第11～20试次，和1～10试次识别方法相同，只是标称频率的顺序相反
                        [Ml,Il] = max(p(f>=2*f_table(21-i)-5 & f<=2*f_table(21-i)+5));
                        if f(I)-f_table(21-i)<=0.1
                            count=count+1;
                            result2(i,j)=result2(i,j)+1;
                        elseif f(Il)-2*f_table(21-i)<=0.1
                            count = count+1;
                            result2(i,j)=result2(i,j)+1;
                        end
                    end
                end
                if count>=5
                    result(i)=1;     % 若8个导联中至少有5个识别成功，则认为该试次识别成功
                    count_total = count_total+1;  % 记录识别成功的试次个数
                else
                    result(i)=0;
                end
            end
            accuracy = append(num2str(count_total/20*100),'%');  % 计算识别准确率
            % 显示识别结果，result2存储的是每个试次下的每个导联的识别结果，1表示识别成功，0表示识别失败
            % result存储的是每个试次的8导联联合识别结果，1表示识别成功，0表示识别失败
           d = {'导联1',result2(1,1),result2(2,1),result2(3,1),result2(4,1),result2(5,1),result2(6,1),result2(7,1),result2(8,1),result2(9,1),result2(10,1),...
                result2(11,1),result2(12,1),result2(13,1),result2(14,1),result2(15,1),result2(16,1),result2(17,1),result2(18,1),result2(19,1),result2(20,1);...
                '导联2',result2(1,2),result2(2,2),result2(3,2),result2(4,2),result2(5,2),result2(6,2),result2(7,2),result2(8,2),result2(9,2),result2(10,2),...
                result2(11,2),result2(12,2),result2(13,2),result2(14,2),result2(15,2),result2(16,2),result2(17,2),result2(18,2),result2(19,2),result2(20,2);...
                '导联3',result2(1,3),result2(2,3),result2(3,3),result2(4,3),result2(5,3),result2(6,3),result2(7,3),result2(8,3),result2(9,3),result2(10,3),...
                result2(11,3),result2(12,3),result2(13,3),result2(14,3),result2(15,3),result2(16,3),result2(17,3),result2(18,3),result2(19,3),result2(20,3);...
                '导联4',result2(1,4),result2(2,4),result2(3,4),result2(4,4),result2(5,4),result2(6,4),result2(7,4),result2(8,4),result2(9,4),result2(10,4),...
                result2(11,4),result2(12,4),result2(13,4),result2(14,4),result2(15,4),result2(16,4),result2(17,4),result2(18,4),result2(19,4),result2(20,4);...
                '导联5',result2(1,5),result2(2,5),result2(3,5),result2(4,5),result2(5,5),result2(6,5),result2(7,5),result2(8,5),result2(9,5),result2(10,5),...
                result2(11,5),result2(12,5),result2(13,5),result2(14,5),result2(15,5),result2(16,5),result2(17,5),result2(18,5),result2(19,5),result2(20,5);...
                '导联6',result2(1,6),result2(2,6),result2(3,6),result2(4,6),result2(5,6),result2(6,6),result2(7,6),result2(8,6),result2(9,6),result2(10,6),...
                result2(11,6),result2(12,6),result2(13,6),result2(14,6),result2(15,6),result2(16,6),result2(17,6),result2(18,6),result2(19,6),result2(20,6);...
                '导联7',result2(1,7),result2(2,7),result2(3,7),result2(4,7),result2(5,7),result2(6,7),result2(7,7),result2(8,7),result2(9,7),result2(10,7),...
                result2(11,7),result2(12,7),result2(13,7),result2(14,7),result2(15,7),result2(16,7),result2(17,7),result2(18,7),result2(19,7),result2(20,7);...
                '导联8',result2(1,8),result2(2,8),result2(3,8),result2(4,8),result2(5,8),result2(6,8),result2(7,8),result2(8,8),result2(9,8),result2(10,8),...
                result2(11,8),result2(12,8),result2(13,8),result2(14,8),result2(15,8),result2(16,8),result2(17,8),result2(18,8),result2(19,8),result2(20,8);...
                '8导联联合识别结果',result(1),result(2),result(3),result(4),result(5),result(6),result(7),result(8),result(9),result(10),result(11),result(12),...
                result(13),result(14),result(15),result(16),result(17),result(18),result(19),result(20);...
                '识别准确率',accuracy,'','','','','','','','','','','','','','','','','','',''};
                app.UITable.Data = d;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '请选择要产生的波形';
            app.Button_sin = uiradiobutton(app.ButtonGroup);
            app.Button_sin.Text = '正弦波';
            app.Button_sin.Value = true;
            app.Button_rect = uiradiobutton(app.ButtonGroup);
            app.Button_rect.Text = '方波';
            app.Button_mixedsin = uiradiobutton(app.ButtonGroup);
            app.Button_mixedsin.Text = '混合正弦波';
            app.Button_WGN = uiradiobutton(app.ButtonGroup);
            app.Button_WGN.Text = '高斯白噪声';
            app.Button_WUN = uiradiobutton(app.ButtonGroup);
            app.Button_WUN.Text = '均匀白噪声';
            app.Button_SSVEP = uiradiobutton(app.ButtonGroup);
            app.Button_SSVEP.Text = '加载SSVEP信号';
            app.fHzEditFieldLabel = uilabel(app.UIFigure);
            app.fHzEditFieldLabel.Text = '频率f/Hz';
            app.fHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.fHzEditField.Value = 20;
            app.LsEditFieldLabel = uilabel(app.UIFigure);
            app.LsEditFieldLabel.Text = '时间长度L/s';
            app.LsEditField = uieditfield(app.UIFigure, 'numeric');
            app.LsEditField.Value = 1;
            app.fsHzEditFieldLabel = uilabel(app.UIFigure);
            app.fsHzEditFieldLabel.Text = '采样率fs/Hz';
            app.fsHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.fsHzEditField.Value = 1000;
            app.phiEditFieldLabel = uilabel(app.UIFigure);
            app.phiEditFieldLabel.Text = '初相phi/度';
            app.phiEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Visible = 'off';
            app.aEditFieldLabel.Text = '占空比a/%';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditField.Visible = 'off';
            app.aEditField.Value = 25;
            app.AcmEditFieldLabel = uilabel(app.UIFigure);
            app.AcmEditFieldLabel.Text = '幅度A/cm';
            app.AcmEditField = uieditfield(app.UIFigure, 'numeric');
            app.AcmEditField.Value = 1;
            app.A1cmEditFieldLabel = uilabel(app.UIFigure);
            app.A1cmEditFieldLabel.Visible = 'off';
            app.A1cmEditFieldLabel.Text = '幅度A1/cm';
            app.A1cmEditField = uieditfield(app.UIFigure, 'numeric');
            app.A1cmEditField.Visible = 'off';
            app.A1cmEditField.Value = 1;
            app.A2cmEditFieldLabel = uilabel(app.UIFigure);
            app.A2cmEditFieldLabel.Visible = 'off';
            app.A2cmEditFieldLabel.Text = '幅度A2/cm';
            app.A2cmEditField = uieditfield(app.UIFigure, 'numeric');
            app.A2cmEditField.Visible = 'off';
            app.A2cmEditField.Value = 1;
            app.f2HzEditFieldLabel = uilabel(app.UIFigure);
            app.f2HzEditFieldLabel.Visible = 'off';
            app.f2HzEditFieldLabel.Text = '频率f2/Hz';
            app.f2HzEditField = uieditfield(app.UIFigure, 'numeric');
            app.f2HzEditField.Visible = 'off';
            app.f2HzEditField.Value = 300;
            app.f1HzEditField_2Label = uilabel(app.UIFigure);
            app.f1HzEditField_2Label.Visible = 'off';
            app.f1HzEditField_2Label.Text = '频率f1/Hz';
            app.f1HzEditField = uieditfield(app.UIFigure, 'numeric');
            app.f1HzEditField.Visible = 'off';
            app.f1HzEditField.Value = 30;
            app.phi1EditFieldLabel = uilabel(app.UIFigure);
            app.phi1EditFieldLabel.Visible = 'off';
            app.phi1EditFieldLabel.Text = '初相phi1/度';
            app.phi1EditField = uieditfield(app.UIFigure, 'numeric');
            app.phi1EditField.Visible = 'off';
            app.phi2EditFieldLabel = uilabel(app.UIFigure);
            app.phi2EditFieldLabel.Visible = 'off';
            app.phi2EditFieldLabel.Text = '初相phi2/度';
            app.phi2EditField = uieditfield(app.UIFigure, 'numeric');
            app.phi2EditField.Visible = 'off';
            app.FFTCheckBox = uicheckbox(app.UIFigure);
            app.FFTCheckBox.Text = 'FFT分析';
            app.PSDCheckBox = uicheckbox(app.UIFigure);
            app.PSDCheckBox.Text = '功率谱分析';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '请选择频谱分析的类型：';
            app.meanEditFieldLabel = uilabel(app.UIFigure);
            app.meanEditFieldLabel.Visible = 'off';
            app.meanEditFieldLabel.Text = '均值';
            app.meanEditField = uieditfield(app.UIFigure, 'numeric');
            app.meanEditField.Visible = 'off';
            app.sigma2EditFieldLabel = uilabel(app.UIFigure);
            app.sigma2EditFieldLabel.Tag = 'sigma2EditFieldLabel';
            app.sigma2EditFieldLabel.Visible = 'off';
            app.sigma2EditFieldLabel.Text = '方差';
            app.sigma2EditField = uieditfield(app.UIFigure, 'numeric');
            app.sigma2EditField.Tag = 'sigma2EditFieldLabel';
            app.sigma2EditField.Visible = 'off';
            app.sigma2EditField.Value = 1;
            app.Buttonwave = uibutton(app.UIFigure, 'push');
            app.Buttonwave.ButtonPushedFcn = createCallbackFcn(app, @ButtonwavePushed, true);
            app.Buttonwave.Text = '产生时域波形';
            app.Buttonspectrum = uibutton(app.UIFigure, 'push');
            app.Buttonspectrum.ButtonPushedFcn = createCallbackFcn(app, @ButtonspectrumPushed, true);
            app.Buttonspectrum.Text = '进行频谱分析';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '欢迎开始实验！';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '请选择要进行的实验类型：';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '实验类型';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'信号产生及频谱分析', '数字低通滤波器设计与滤波', '数字高通滤波器设计与滤波', 'ssvep信号识别'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '信号产生及频谱分析';
            app.fcHzLabel = uilabel(app.UIFigure);
            app.fcHzLabel.Visible = 'off';
            app.fcHzLabel.Text = '通带截止频率fc/Hz';
            app.fcHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.fcHzEditField.Visible = 'off';
            app.fcHzEditField.Value = 100;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Visible = 'off';
            app.Label_5.Text = '请选择低通滤波器类型：';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'巴特沃斯数字滤波器', '切比雪夫1型数字滤波器', '切比雪夫2型数字滤波器', '椭圆数字滤波器'};
            app.DropDown_2.Visible = 'off';
            app.DropDown_2.Value = '巴特沃斯数字滤波器';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Visible = 'off';
            app.Label_6.Text = '请选择对滤波器的操作：';
            app.DropDown_3 = uidropdown(app.UIFigure);
            app.DropDown_3.Items = {'显示幅频、相频响应', '对混合正弦波滤波，显示幅度谱', '对混合正弦波滤波，显示相位谱'};
            app.DropDown_3.Visible = 'off';
            app.DropDown_3.Value = '显示幅频、相频响应';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Visible = 'off';
            app.Button_3.Text = '开始滤波器分析';
            app.dHzLabel = uilabel(app.UIFigure);
            app.dHzLabel.Visible = 'off';
            app.dHzLabel.Text = '低通滤波器过渡带宽度d/Hz';
            app.dHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.dHzEditField.Visible = 'off';
            app.dHzEditField.Value = 60;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '图像';
            app.UIAxes1 = uiaxes(app.Tab);
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes2 = uiaxes(app.Tab);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.Tab);
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.Tab);
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '表格';
            app.UITable = uitable(app.Tab_2);
            app.UITable.ColumnName = {''; '试次1'; '试次2'; '试次3'; '试次4'; '试次5'; '试次6'; '试次7'; '试次8'; '试次9'; '试次10'; '试次11'; '试次12'; '试次13'; '试次14'; '试次15'; '试次16'; '试次17'; '试次18'; '试次19'; '试次20'};
            app.UITable.RowName = {};
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Visible = 'off';
            app.Label_7.Text = '请选择要查看的试次';
            app.DropDown_4 = uidropdown(app.UIFigure);
            app.DropDown_4.Items = {'试次1', '试次2', '试次3', '试次4', '试次5', '试次6', '试次7', '试次8', '试次9', '试次10', '试次11', '试次12', '试次13', '试次14', '试次15', '试次16', '试次17', '试次18', '试次19', '试次20'};
            app.DropDown_4.Visible = 'off';
            app.DropDown_4.Value = '试次1';
            app.ssvepButton = uibutton(app.UIFigure, 'push');
            app.ssvepButton.ButtonPushedFcn = createCallbackFcn(app, @ssvepButtonPushed, true);
            app.ssvepButton.Visible = 'off';
            app.ssvepButton.Text = '查看ssvep信号时域频域图像';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Visible = 'off';
            app.Button_4.Text = '对20个试次进行目标识别(支持功率谱分析)';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Visible = 'off';
            app.Label_8.Text = '请选择频谱分析类型';
            app.DropDown_5 = uidropdown(app.UIFigure);
            app.DropDown_5.Items = {'FFT', '功率谱'};
            app.DropDown_5.Visible = 'off';
            app.DropDown_5.Value = 'FFT';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Visible = 'off';
            app.Label_9.Text = '请选择滤波器参数：';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end